classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BrowsButton                matlab.ui.control.Button
        SwitchLabel                matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Label                      matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        TypeAlgorthmDropDownLabel  matlab.ui.control.Label
        TypeAlgorthmDropDown       matlab.ui.control.DropDown
        PhotoPathEditFieldLabel    matlab.ui.control.Label
        PhotoPathEditField         matlab.ui.control.EditField
        image                      matlab.ui.control.UIAxes
        showhisgramButton          matlab.ui.control.Button
        FouriertransformButton     matlab.ui.control.Button
        inverusFourierButton       matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        orimage=[];
        modimage=[];
        tmpimage=[];
        path=0;
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.image, '')
            xlabel(app.image, '')
            ylabel(app.image, '')
            app.image.XAxis.TickLabels={};
            app.image.YAxis.TickLabels={};
             I=imshow(imread('.\files\image icones\placeholder.jpg'),'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]);
             app.image.XLim=[0 I.XData(2)];    
             app.image.YLim=[0 I.YData(2)];
              
            
        end

        % Button pushed function: BrowsButton
        function BrowsButtonPushed(app, event)
            [filename , app.path]=uigetfile({"*.jpg";"*.bng";"*.bmp";"*.tif";"*.gif";"*.jpeg";"*.*"},"photo select");
            app.PhotoPathEditField.Value=strcat(app.path,filename);
            %for show ui
          
            %for show image 
            if(app.path ~=0)
            app.orimage=imread(app.PhotoPathEditField.Value);
           app.modimage=app.orimage;
            M=imshow(app.modimage, 'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]);
            app.image.XLim=[0 M.XData(2)];    
            app.image.YLim=[0 M.YData(2)];
            else
             I=imshow(imread('.\files\image icones\placeholder.jpg'),'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]);
             app.image.XLim=[0 I.XData(2)];    
             app.image.YLim=[0 I.YData(2)];
             
            end


            app.UIFigure.Visible='off';
            app.UIFigure.Visible='on';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if(app.path ~=0)
            
            
            if strcmp(value,'On')==1
                
            IM=imshow(app.orimage,'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]); 
            app.image.XLim=[0 IM.XData(2)];    
            app.image.YLim=[0 IM.YData(2)];
             
            else
            app.tmpimage=(app.Slider.Value/100*app.modimage)+((1-(app.Slider.Value/100))*app.orimage);   
            IM=imshow(app.tmpimage,'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]); 
            app.image.XLim=[0 IM.XData(2)];    
            app.image.YLim=[0 IM.YData(2)];
            
            
             end 
            else  
                
         I=imshow(imread('.\files\image icones\placeholder.jpg'),'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]);
             app.image.XLim=[0 I.XData(2)];    
             app.image.YLim=[0 I.YData(2)];
                
              
            end
        end

        % Value changed function: TypeAlgorthmDropDown
        function TypeAlgorthmDropDownValueChanged(app, event)
            value = app.TypeAlgorthmDropDown.Value;
            
            switch value
                case 'RGB2GRAY'
                   app.modimage=app.orimage;
                   app.modimage= app.modimage(:,:,1);
                   app.tmpimage=(app.Slider.Value/100*app.modimage)+((1-(app.Slider.Value/100))*app.orimage);   
                   display('done');
                case 'GARY2BINARY'
                           app.modimage=app.orimage;    
                          [hight,width,depth]=size(app. modimage);
                            for i=1:hight
                             for j=1:width
                              for z=1:depth 
                                 if(app.modimage(i,j,z)>256/2)
                                  app.modimage(i,j,z)=255;
                                 else
                                  app.modimage(i,j,z)=0;
                                 end
                              end
                             end
                            end
                             display('done');

                    
                case 'brightness +'
                    app.modimage=app.orimage;
                    img1=app.modimage;
                    img2=double(img1);
                    [w,h,l]=size(img2);
                    newimg=[];
                    for i=1:w
                        for j=1:h
                         for z=1:l
                            newimg(i,j,z)=150 + img2(i,j,z);  
                         end
                        end
                    end
                    app.modimage=uint8(newimg);
                    display('done');    
                    
                case 'brightness *'
                    app.modimage=app.orimage;
                      img1=app.modimage;
                    img2=double(img1);
                    [w,h,l]=size(img2);
                    newimg=[];
                    for i=1:w
                        for j=1:h
                         for z=1:l
                            newimg(i,j,z)=60 * img2(i,j,z);  
                         end
                        end
                    end
                    app.modimage=uint8(newimg);
                    display('done');  
       
                    
                case'drakness -'
                    app.modimage=app.orimage;
                      img1=app.modimage;
                    img2=double(img1);
                    [w,h,l]=size(img2);
                    newimg=[];
                    for i=1:w
                        for j=1:h
                         for z=1:l
                            newimg(i,j,z)= img2(i,j,z)-60;  
                         end
                        end
                    end
                    app.modimage=uint8(newimg);
                    display('done');  
                    
                    
                case'drakness /'
                    app.modimage=app.orimage;
                      img1=app.modimage;
                    img2=double(img1);
                    [w,h,l]=size(img2);
                    newimg=[];
                    for i=1:w
                        for j=1:h
                         for z=1:l
                            newimg(i,j,z)= img2(i,j,z)/60;  
                         end
                        end
                    end
                    app.modimage=uint8(newimg);
                    display('done');
                case 'negative'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    img1=double(img);
                    [w,h,l]=size(img1);
                    newimg=[];
                    
                    for i=1:w
                        for j=1:h
                            for z=1:l
                             newimg(i,j,z) = 255 - img1(i,j,z);  
                             
                            end
                        end
                    end
                  app.modimage=uint8(newimg);
         
                display('done');
                    
                    
                case'Gamma Correction'
                     app.modimage=app.orimage;
                    [w,h,l]=size(app.modimage);
                   
                    
                            for i=1:w
                                for j=1:h
                                    for z=1:l
                                   app.modimage(i,j,z)=power(app.modimage(i,j,z),20);
                                    end
                                end
                            end
                   
                            app.modimage=uint8(app.modimage);
                            
                    display('done');
                case'Expontional'
                       app.modimage=app.orimage;
                    [w,h,l]=size(app.modimage);
                   for i=1:w
                      for j=1:h
                          for z=1:l 
                            in = double(app.modimage(i,j,z)+1);
                            L(i,j,z) = exp(in);
                           end
                       end
                   end
                   app.modimage = (L/(max(L(:))))*255; 
                    app.modimage=uint8(app.modimage);
                   
                      display('done');
                   

                case 'log'
                     app.modimage=app.orimage;
                    [w,h,l]=size(app.modimage);
                   for i=1:w
                      for j=1:h
                          for z=1:l 
                            in = double(app.modimage(i,j,z)+1);
                            L(i,j,z) = log2(in);
                           end
                       end
                   end
                   app.modimage = (L/(max(L(:))))*255; 
                    app.modimage=uint8(app.modimage);
                   
                      display('done');


                case'histogram equaltions'
                    app.modimage=app.orimage;
                    
                    img=app.modimage;
                    [rows,columns,l] = size(img);
                    finalResult = uint8(zeros(rows,columns));
                    pixelNumber = rows*columns;
                    frequncy = zeros(256,1);
                    pdf = zeros(256,1);
                    cdf = zeros(256,1);
                    cummlative = zeros(256,1);
                    outpic = zeros(256,1);
                    for i = 1:1:rows
                        for j = 1:1:columns
                            val = img(i,j);
                            frequncy(val+1) = frequncy(val+1)+1;
                            pdf(val+1) = frequncy(val+1)/pixelNumber;
                        end
                    end
                    
                    sum =0 ;
                    intensityLevel = 255;
                    
                    for i = 1:1:size(pdf)
                        sum =sum +frequncy(i);
                        cummlative(i) = sum;
                        cdf(i) = cummlative(i)/ pixelNumber;
                        outpic(i) = round(cdf(i) * intensityLevel);
                    end
                    
                    
                  for i = 1:1:rows
                    for j = 1:1:columns
                      for z = 1:l
                          finalResult(i,j,z) = outpic(img(i,j,z) + 1);
                        end
                      end
                  end
                  app.modimage=finalResult;
                   display('done');
                case'Contrast Stretching 6.1000'
                    app.modimage=app.orimage;
                    
                    img=app.modimage;
                    
                    img1=double(app.modimage);
                    [h w l]=size(img1)
                    newimg=[h;w;l];
                       oldm=min(min(min(img1)));
                       oldma=max(max(max(img1)));
                    
                            
                     for z=1:l   
                        for i=1:h
                            for j=1:w
                    
                                 newimg(i,j,z)=((img1(i,j,z)-oldm)/(oldma-oldm))*(1000-6)+6;
                              
                            end
                        end
                     end
                    
                    app.modimage=uint8(newimg);
                     display('done'); 
                     
                     case'Contrast Stretching 6.60'
                     app.modimage=app.orimage;
                    
                    img1=app.modimage;
                    img1=double(app.modimage);
                    [h w l]=size(img1)
                    newimg=[h;w;l];
                       oldm=min(min(min(img1)));
                       oldma=max(max(max(img1)));
                    
                            
                     for z=1:l   
                        for i=1:h
                            for j=1:w
                    
                                 newimg(i,j,z)=((img1(i,j,z)-oldm)/(oldma-oldm))*(60-6)+6;
                              
                            end
                        end
                     end
                    
                    app.modimage=uint8(newimg);
                     display('done'); 


                case'WeightFilter'
                    app.modimage=app.orimage;
                    
                       Image=app.modimage;
                        Image = double(Image);
                        Mask=[1/16 1/8 1/16;1/8 1/4 1/8;1/16 1/8 1/16];
                        [l h z] =size(Image);
                        for i=1:z
                         f= filter2(rot90(Image(:,:,i),2),Mask,'full');
                         Image(:,:,i)=f(1:256,1:256);
                         
                        end

                        Image = uint8(Image);
                       app.modimage=Image;
                       display('done');
                    
                case'MeanFilter'
                    app.modimage=app.orimage;
                    Image=app.modimage;
                    Image = double(Image);
                    [l h z] =size(Image);
                    Masked = 1/9*[1 1 1;1 1 1;1 1 1];
                    for i=1:z
                     f= filter2(rot90(Image(:,:,i),2),Masked,'full');
                     Image(:,:,i)=f(1:256,1:256);
                     
                    end

                    app.modimage=uint8(Image);
                    display('done'); 
                    
                case'Mini Filter'
                    app.modimage=app.orimage;
                    mask_h=5;
                    mask_w=5;
                    oldimage=app.modimage;
                    [H W L]=size(oldimage);
                    oldimage=double(oldimage);
                    copy=zeros(mask_h ,mask_w);
                    new_img=zeros(H,W,L);
                    X=floor(mask_h/2);
                    Y=floor(mask_w/2);
                    for i=1:H-mask_h 
                     for j=1:W-mask_w
                      for m=1:L
                       for k=1:mask_h
                            for l=1:mask_w
                                copy(k,l)=oldimage(i+k-1,j+l-1,m) ;
                            end
                       end
                         new_img(i+X,j+Y,m)=min(copy(:));
                      end
                     end 
                    end
                    app.modimage=uint8(new_img);
                    display('done'); 
                case'max Filter'
                    app.modimage=app.orimage;
                    mask_h=5;
                    mask_w=5;
                    oldimage=app.modimage;
                    [H W L]=size(oldimage);
                    oldimage=double(oldimage);
                    copy=zeros(mask_h ,mask_w);
                    new_img=zeros(H,W,L);
                    X=floor(mask_h/2);
                    Y=floor(mask_w/2);
                    for i=1:H-mask_h 
                     for j=1:W-mask_w
                      for m=1:L
                       for k=1:mask_h
                         for l=1:mask_w
                             copy(k,l)=oldimage(i+k-1,j+l-1,m) ; 
                         end
                       end
                             new_img(i+X,j+Y,m)=max(copy(:));
                      end
                     end 
                    end
                     app.modimage=uint8(new_img);
                     display('done'); 
                case 'median filter' 
                    app.modimage=app.orimage;
                    mask_h=5;
                    mask_w=5;
                    oldimage=app.modimage;
                    X=floor(mask_h/2);
                    Y=floor(mask_w/2);
                    Z=floor(mask_h/2)+floor(mask_w/2)+1;
                    [H W L]=size(oldimage);
                    oldimage=double(oldimage);
                    copy=zeros(mask_h ,mask_w);
                    sorted=zeros(mask_h*mask_w,1); 
                    new_img=zeros(H,W,L);
                    for i=1:H-mask_h 
                     for j=1:W-mask_w
                      for m=1:L
                       for k=1:mask_h
                        for l=1:mask_w
                             copy(k,l)=oldimage(i+k-1,j+l-1,m) ;
                        end
                       end
                         sorted=sort(copy);
                         new_img(i+X,j+Y,m)=sorted(Z,1);
                      end
                     end 
                    end
                    app.modimage=uint8(new_img);
                    display('done'); 
                case'salt & pepper noise'
                    app.modimage=app.orimage;
                    Img=app.modimage;
                    [h w l] =size(app.modimage);
                    [h w l] = size(Img);
                    nums=.06*h*w; %editable
                    nump=.06*h*w; %editable
                    Img = double(Img);
                  for z=1:l
                      
                    for i=1:nums-1
                        row = round(rand(1,1)*w)+1;
                        col = round(rand(1,1)*h)+1;
                        Img(col,row,z)=w*h;
                    end
                    for i = 1 :nump-1
                        row = round(rand(1,1)*w)+1;
                        col = round(rand(1,1)*h)+1;
                        Img(col,row,z)=h*w;
                    end
                  end
                [hh ww ll] =size(Img);
                  if(h~=hh||w~=ww||l~=ll)
                      hh=h;
                      ww=w;
                      ll=l;
                      
                  end
                   
                   
                app.modimage=uint8(Img(1:hh,1:ww,1:ll));
                display('done'); 
                case'Butterworth_highpass'
                    d0=90;
                    n=30;
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                [H, W, l] = size(img1);
                mask = zeros(H, W);
                newimg=[];
                for i = 1:H
                	for j = 1:W
                		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
                		mask(i,j)=1/(1+(d0/D(i,j))^(2*n));
                                    
                		end
                	end
                
                if l==1
                    fi=fft2(img1);
                fshift=fftshift(fi);
                
                    for a=1:H
                        for b=1:W
                            newimg(a,b) = fshift(a,b)*mask(a,b);
                        end
                    end
                
                    
                newimg_shift=fftshift(newimg);
                imn=ifft2(newimg_shift);
               
                else
                     i1=img1(:,:,1);
                 i2=img1(:,:,2);
                 i3=img1(:,:,3);
                 fi1=fft2(i1);
                 fi2=fft2(i2);
                 fi3=fft2(i3);
                 fshift1=fftshift(fi1);
                 fshift2=fftshift(fi2);
                 fshift3=fftshift(fi3);
                   for a=1:H
                        for b=1:W
                            newimg1(a,b) = fshift1(a,b)*mask(a,b);
                            newimg2(a,b) = fshift2(a,b)*mask(a,b);
                            newimg3(a,b) = fshift3(a,b)*mask(a,b);
                        end
                   end
                    newimg_shift1=fftshift(newimg1);
                    newimg_shift2=fftshift(newimg2);
                    newimg_shift3=fftshift(newimg3);
                    inverse11=ifft2(newimg_shift1);
                    inverse12=ifft2(newimg_shift2);
                    inverse13=ifft2(newimg_shift3);
                    imn(:,:,1)=inverse11;
                    imn(:,:,2)=inverse12;
                    imn(:,:,3)=inverse13;
                  
                end
                                                      
                    
                    app.modimage=uint8(real(imn));
                    
                     display('done');
                case 'Butterworth_lowpass'
                  d0=15;
                    n=30;
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                [H, W, l] = size(img1);
                mask = zeros(H, W);
                newimg=[];
                    for i = 1:H
                    	for j = 1:W
                    		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
                    		mask(i,j)=1/(1+(D(i,j)/d0)^(2*n));
                                        
                    		end
                    	end
                    
                    if l==1
                        fi=fft2(img1);
                        fshift=fftshift(fi);
                        for a=1:H
                            for b=1:W
                                newimg(a,b) = fshift(a,b)*mask(a,b);
                            end
                        end
                    
                        
                    newimg_shift=fftshift(newimg);
                    imgn=ifft2(newimg_shift);
                    else
                         i1=img1(:,:,1);
                     i2=img1(:,:,2);
                     i3=img1(:,:,3);
                     fi1=fft2(i1);
                     fi2=fft2(i2);
                     fi3=fft2(i3);
                     fshift1=fftshift(fi1);
                     fshift2=fftshift(fi2);
                     fshift3=fftshift(fi3);
                       for a=1:H
                            for b=1:W
                                newimg1(a,b) = fshift1(a,b)*mask(a,b);
                                newimg2(a,b) = fshift2(a,b)*mask(a,b);
                                newimg3(a,b) = fshift3(a,b)*mask(a,b);
                            end
                       end
                        newimg_shift1=fftshift(newimg1);
                        newimg_shift2=fftshift(newimg2);
                        newimg_shift3=fftshift(newimg3);
                        inverse11=ifft2(newimg_shift1);
                        inverse12=ifft2(newimg_shift2);
                        inverse13=ifft2(newimg_shift3);
                        imgn(:,:,1)=inverse11;
                        imgn(:,:,2)=inverse12;
                        imgn(:,:,3)=inverse13;
                      
                    end


                      app.modimage=uint8(real(imgn));
                       display('done'); 
              case'Gaussian_lowpass'
                    d0=20;
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                [H, W, l] = size(img1);
                mask = zeros(H, W);
                newimg=[];
            for i = 1:H
            	for j = 1:W
            		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
            		mask(i,j)= exp ( -( (D(i,j)^2)/(2*(d0^2)) ) );
                                
            		end
            	end
            
            if l==1
                fi=fft2(img1);
            fshift=fftshift(fi);
                for a=1:H
                    for b=1:W
                        newimg(a,b) = fshift(a,b)*mask(a,b);
                    end
                end
            
                
            newimg_shift=fftshift(newimg);
            imgn=ifft2(newimg_shift);
         
            
            else
                 i1=img1(:,:,1);
             i2=img1(:,:,2);
             i3=img1(:,:,3);
             fi1=fft2(i1);
             fi2=fft2(i2);
             fi3=fft2(i3);
             fshift1=fftshift(fi1);
             fshift2=fftshift(fi2);
             fshift3=fftshift(fi3);
               for a=1:H
                    for b=1:W
                        newimg1(a,b) = fshift1(a,b)*mask(a,b);
                        newimg2(a,b) = fshift2(a,b)*mask(a,b);
                        newimg3(a,b) = fshift3(a,b)*mask(a,b);
                    end
               end
                newimg_shift1=fftshift(newimg1);
                newimg_shift2=fftshift(newimg2);
                newimg_shift3=fftshift(newimg3);
                inverse11=ifft2(newimg_shift1);
                inverse12=ifft2(newimg_shift2);
                inverse13=ifft2(newimg_shift3);
                imgn(:,:,1)=inverse11;
                imgn(:,:,2)=inverse12;
                imgn(:,:,3)=inverse13;
              
            end 
              app.modimage=uint8(real(imgn));   
               display('done'); 
                  
                case'Gaussian_highpas'     
              
                     d0=90;
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                [H, W, l] = size(img1);
                mask = zeros(H, W);
                newimg=[];
                    for i = 1:H
                    	for j = 1:W
                    		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
                    		mask(i,j)= 1-exp ( -( (D(i,j)^2)/(2*(d0^2)) ) );
                                        
                    		end
                    	end
                    
                    if l==1
                        fi=fft2(img1);
                        fshift=fftshift(fi);
                        for a=1:H
                            for b=1:W
                                newimg(a,b) = fshift(a,b)*mask(a,b);
                            end
                        end
                    
                        
                    newimg_shift=fftshift(newimg);
                    imgn=ifft2(newimg_shift);
                    
                    else
                         i1=img1(:,:,1);
                     i2=img1(:,:,2);
                     i3=img1(:,:,3);
                     fi1=fft2(i1);
                     fi2=fft2(i2);
                     fi3=fft2(i3);
                     fshift1=fftshift(fi1);
                     fshift2=fftshift(fi2);
                     fshift3=fftshift(fi3);
                       for a=1:H
                            for b=1:W
                                newimg1(a,b) = fshift1(a,b)*mask(a,b);
                                newimg2(a,b) = fshift2(a,b)*mask(a,b);
                                newimg3(a,b) = fshift3(a,b)*mask(a,b);
                            end
                       end
                        newimg_shift1=fftshift(newimg1);
                        newimg_shift2=fftshift(newimg2);
                        newimg_shift3=fftshift(newimg3);
                        inverse11=ifft2(newimg_shift1);
                        inverse12=ifft2(newimg_shift2);
                        inverse13=ifft2(newimg_shift3);
                        imgn(:,:,1)=inverse11;
                        imgn(:,:,2)=inverse12;
                        imgn(:,:,3)=inverse13;
                    end
                    app.modimage=uint8(real(imgn));
                     display('done'); 
                case'ideal_low'
                    
                    d0=15;             
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                   [H, W, l] = size(img1);
                   mask = zeros(H, W);
                   newimg=[];
                    
                    %make filter
                    for i = 1:H
                    	for j = 1:W
                    		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
                    		if D(i,j) <= d0 
                    			mask(i, j) = 1;
                    		else
                    			mask(i, j) = 0;
                    		end
                    	end
                    end
                    
                    if l==1
                        fi=fft2(img1);
                        fshift=fftshift(fi);
                        for a=1:H
                            for b=1:W
                                newimg(a,b) = fshift(a,b)*mask(a,b);
                            end
                        end
                        newimg_shift=fftshift(newimg);
                    imgn=ifft2(newimg_shift);
                    
                    
                    else
                     i1=img1(:,:,1);
                     i2=img1(:,:,2);
                     i3=img1(:,:,3);
                     fi1=fft2(i1);
                     fi2=fft2(i2);
                     fi3=fft2(i3);
                     fshift1=fftshift(fi1);
                     fshift2=fftshift(fi2);
                     fshift3=fftshift(fi3);
                       for a=1:H
                            for b=1:W
                                newimg1(a,b) = fshift1(a,b)*mask(a,b);
                                newimg2(a,b) = fshift2(a,b)*mask(a,b);
                                newimg3(a,b) = fshift3(a,b)*mask(a,b);
                            end
                       end
                        newimg_shift1=fftshift(newimg1);
                        newimg_shift2=fftshift(newimg2);
                        newimg_shift3=fftshift(newimg3);
                        inverse11=ifft2(newimg_shift1);
                        inverse12=ifft2(newimg_shift2);
                        inverse13=ifft2(newimg_shift3);
                        imgn(:,:,1)=inverse11;
                        imgn(:,:,2)=inverse12;
                        imgn(:,:,3)=inverse13;
                       
                    end
                    app.modimage=uint8(real(imgn)); 
                     display('done'); 
                    
                case'ideal_high'
                   
                    d0=90;             
                    app.modimage=app.orimage;
                   img1=app.modimage;
                   img1 = double(img1);
                   [H, W, l] = size(img1);
                   mask = zeros(H, W);
                   newimg=[];
                    
                    %make filter
                    for i = 1:H
                    	for j = 1:W
                    		D(i,j) =sqrt( (i - H/2)^2 + (j - W/2)^2 );
                    		if D(i,j) <= d0 
                    			mask(i, j) = 0;
                    		else
                    			mask(i, j) = 1;
                    		end
                    	end
                    end
                    
                    if l==1
                        fi=fft2(img1);
                        fshift=fftshift(fi);
                        for a=1:H
                            for b=1:W
                                newimg(a,b) = fshift(a,b)*mask(a,b);
                            end
                        end
                        newimg_shift=fftshift(newimg);
                    imgn=ifft2(newimg_shift);
                     
                    
                    else
                     i1=img1(:,:,1);
                     i2=img1(:,:,2);
                     i3=img1(:,:,3);
                     fi1=fft2(i1);
                     fi2=fft2(i2);
                     fi3=fft2(i3);
                     fshift1=fftshift(fi1);
                     fshift2=fftshift(fi2);
                     fshift3=fftshift(fi3);
                       for a=1:H
                            for b=1:W
                                newimg1(a,b) = fshift1(a,b)*mask(a,b);
                                newimg2(a,b) = fshift2(a,b)*mask(a,b);
                                newimg3(a,b) = fshift3(a,b)*mask(a,b);
                            end
                       end
                        newimg_shift1=fftshift(newimg1);
                        newimg_shift2=fftshift(newimg2);
                        newimg_shift3=fftshift(newimg3);
                        inverse11=ifft2(newimg_shift1);
                        inverse12=ifft2(newimg_shift2);
                        inverse13=ifft2(newimg_shift3);
                        imgn(:,:,1)=inverse11;
                        imgn(:,:,2)=inverse12;
                        imgn(:,:,3)=inverse13;
                      
                    end
                    app.modimage=uint8(real(imgn)); 
                     display('done'); 
                case'uniform_noise 50 1500'
                   b=1500;
                   a=50;
                    app.modimage=app.orimage;
                   imag=app.modimage;
                [h w l]=size(imag);
                numberofpixel=round(abs((1/(b-a))*w*h));
                for j=1:l
                    for i=1:255
                
                        for x=1:numberofpixel
                            r=ceil(rand(1,1)*h);
                            c=ceil(rand(1,1)*w);
                
                            if(r~=0&&c~=0)
                                imag(r,c,j)=imag(r,c,j)+i;
                            end
                
                        end
                    end
                end
                for q=1:l
                    mi=min(min(imag(:,:,q)));
                    mx=max(max(imag(:,:,q)));
                    newimage(:,:,q)=((imag(:,:,q)-mi)/(mx-mi))*255;
                end
                newimage=imag;
                app.modimage=newimage;
                display('done');
 
                case'gaussian_noise 12 30'
                   v=12;
                   m=30;
                     app.modimage=app.orimage;
                   imag=app.modimage;
                    [h w l]=size(imag);
                    for j=1:l
                        for i=1:255
                         numberofpixel=round(exp(-((i-m)^2/(2*v*v)))/sqrt(2*3.14*v*v)*w*h);
                    
                            for x=1:numberofpixel
                    
                                r=round(rand(1,1)*h);
                                c=round(rand(1,1)*w);
                              if(r~=0&&c~=0)
                                imag(r,c,j)=imag(r,c,j)+i;
                              end
                            end
                        end  
                    end
                     for q=1:l
                         mi=min(min(imag(:,:,q)));
                         mx=max(max(imag(:,:,q)));
                         newimage(:,:,q)=((imag(:,:,q)-mi)/(mx-mi))*255;
                     end
                    newimage=imag;
                    app.modimage=newimage;
                    display('done');


                case'rayleigh_noise 6 90'
          
                   a=6;
                   b=90;
                    app.modimage=app.orimage;
                   imag=app.modimage;
                [H W l]=size(imag);
                for v=1:l
                for z = 1:255
                    if z>=a
                        num_of_pixels=round(((2/b)*(z-a)*exp(-((z-a)^2)/b))*H*W);
                    else
                        num_of_pixels=0;
                    end
                        for j=1:num_of_pixels
                            x = ceil(rand(1, 1)*W);
                            y = ceil(rand(1, 1)*H);
                          
                           if(x~=0&&y~=0)
                                imag(x,y,v)=imag(x,y,v)+z;
                            end
                
                        end
                 end
                end
                for q=1:l
                    mi=min(min(imag(:,:,q)));
                    mx=max(max(imag(:,:,q)));
                    newimage(:,:,q)=((imag(:,:,q)-mi)/(mx-mi))*255;
                end
                newimage=imag;
                app.modimage=newimage;
                display('done'); 
                    
                case'Exponential_noise .05'
                    a=.05;
                    app.modimage=app.orimage;
                   imag=app.modimage;
                    [H W l]=size(imag);
                    
                   for z=1:l 
                    for i=1:255
                        numberofpixel=round((a*exp(-a*i))*W*H);
                        for x=1:numberofpixel
                            r=ceil(rand(1,1)*H);
                            c=ceil(rand(1,1)*W);
                                if(r~=0&&c~=0)
                                    imag(r,c,z)=imag(r,c,z)+i;
                                end
                        end
                    end  
                        
                    end
                    
                    for q=1:l
                        mi=min(min(imag(:,:,q)));
                        mx=max(max(imag(:,:,q)));
                        newimage(:,:,q)=((imag(:,:,q)-mi)/(mx-mi))*255;
                    end
                     newimage=imag;
                     app.modimage=newimage;
                     display('done'); 


                case'Gamma_noise 1.3 5'   
                     
                        a=1.3;
                        b=5;
                        app.modimage=app.orimage;
                        imag=app.modimage;
                        [H W l]=size(imag);
                        for v=1:l
                        for z = 1:255
                            if z>=a
                                num_of_pixels=round((((a^b)*(z^b-1)/factorial((b-1))*exp(-a*z)) )*H*W);
                            else
                                num_of_pixels=0;
                            end
                                for j=1:num_of_pixels
                                    x = ceil(rand(1, 1)*W);
                                    y = ceil(rand(1, 1)*H);
                                  
                                   if(x~=0&&y~=0)
                                        imag(x,y,v)=imag(x,y,v)+z;
                                    end
                        
                                end
                         end
                        end
                        for q=1:l
                            mi=min(min(imag(:,:,q)));
                            mx=max(max(imag(:,:,q)));
                            newimage(:,:,q)=((imag(:,:,q)-mi)/(mx-mi))*255;
                        end
                        newimage=imag;  
                       app.modimage=newimage;
                       display('done'); 
                case'point_detection'
           
                app.modimage=app.orimage;
                img=app.modimage;
                filtter=[-1 -1 -1 ; -1 8 -1 ; -1 -1 -1];
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                  
                
                % display(su);
                % display(sum(sum(m .* filtter)));
                %NImg(i,j,z) = sum(sum(m .* filtter))
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end

                %imshow(img);
                %figure, imshow(uint8(FImg));
                app.modimage=uint8(NImg);
                display('done');
                
                case'Point Sharpening'
                    
                     app.modimage=app.orimage;
                   img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    filtter=[ 0 -1 0;
                    -1 5 -1;
                    0 -1 0 ];
                    [h w l] =size(img);
                    [fh fw] = size(filtter);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                   m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* filtter);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    %imshow(img);
                    %figure, imshow(uint8(FImg));
                    
                    
                     app.modimage=uint8(NImg);
                     display('done');
                case'line_sharpeningH'
                 
                    app.modimage=app.orimage;
                   img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                V=[ 0 0 0;
                1 1 -1;
                0 0 0 ];


                [h w l] =size(img);
                [fh fw] = size(V);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2);
                
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end


                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* V);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                %imshow(img);
                %figure, imshow(uint8(FImg));


                app.modimage=uint8(NImg);
                     display('done');
                case'line_sharpeningDL'                
                
                    
                   app.modimage=app.orimage;
                   img=app.modimage; 
                    
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                DL=[ 0 0 1 ;
                0 1 0 ;
                -1 0 0 ];


                [h w l] =size(img);
                [fh fw] = size(DL);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
             m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* DL);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                %imshow(img);
                %figure, imshow(uint8(FImg));
               
                
                 app.modimage=uint8(NImg);
                     display('done');
                     
                     
                case'line_sharpeningV'
                    
                     app.modimage=app.orimage;
                   img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    H=[ 0 1 0;
                    0 1 0;
                    0 -1 0 ];


                    [h w l] =size(img);
                    [fh fw] = size(H);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end


                   m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* H);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    %imshow(img);
                    %figure, imshow(uint8(FImg));
                    
                    
                     app.modimage=uint8(NImg);
                     display('done');
                     
                case'line_sharpeningDR'
                    
                     app.modimage=app.orimage;
                    img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    DR=[ 1 0 0 ;
                    0 1 0;
                    0 0 -1 ];
                    [h w l] =size(img);
                    [fh fw] = size(DR);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end


                  m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* DR);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                     display('done');
                     
                     
                case'line_DetectionV'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    V=[ 0 1 0;
                    0 0 0;
                    0 -1 0 ];


                    [h w l] =size(img);
                    [fh fw] = size(V);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                    m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* V);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    app.modimage=uint8(NImg);
                    display('done');


                    case'line_DetectionH'
                     app.modimage=app.orimage;
                    img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    v=[ 0 0 0;
                    1 0 -1;
                    0 0 0 ];


                    [h w l] =size(img);
                    [fh fw] = size(v);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end


                   m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* v);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    app.modimage=uint8(NImg);
                    display('done');


                    case'line_DetectionDL'
                           app.modimage=app.orimage;
                    img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    V=[ 1 0 0;
                    0 0 0;
                    0 0 -1 ];


                    [h w l] =size(img);
                    [fh fw] = size(V);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end


                  m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* V);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    app.modimage=uint8(NImg);
                    display('done');
                    
                    
                    case'line_DetectionDR'
                           app.modimage=app.orimage;
                    img=app.modimage;
                    %UNTITLED3 Summary of this function goes here
                    % Detailed explanation goes here
                    V=[ 0 0 1;
                    0 0 0;
                    -1 0 0 ];


                    [h w l] =size(img);
                    [fh fw] = size(V);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                 m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* V);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                    app.modimage=uint8(NImg);
                    display('done');

                case'sebel_H'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    filtter=[-1 -2 -1 ; 0 0 0 ; 1 2 1];
                    [h w l] =size(img);
                    [fh fw] = size(filtter);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2,l);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                    m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* filtter);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                                        
                   app.modimage=uint8(NImg);
                    display('done');
                    
                    case'sebel_v'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    filtter=[-1 0 1 ; -2 0 2 ; -1 0 1];
                    [h w l] =size(img);
                    [fh fw] = size(filtter);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2,l);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                    m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* filtter);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                                        
                   app.modimage=uint8(NImg);
                    display('done');
                    case'sebel_DL'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    filtter=[2 1 0 ; 1 0 -1 ; 0 -1 -2];
                    [h w l] =size(img);
                    [fh fw] = size(filtter);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2,l);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                    m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* filtter);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                                        
                   app.modimage=uint8(NImg);
                    display('done');
                    case'sebel_DR'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    filtter=[0 -1 -2 ; 1 0 -1 ; 2 1 0];
                    [h w l] =size(img);
                    [fh fw] = size(filtter);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2,l);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    
                    m = zeros(fh,fw);
                    a=zeros(fh,fw);
                    su=0;
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    m = FImg(i:i+fh-1, j:j+fh-1,z);
                    [mh,mw]=size(m);
                    a=(m .* filtter);
                        for th=1:mh
                            for tw=1:mw
                                su=su+a(th,tw);
                            end
                        end
                    NImg(i,j,z) = su;
                    su=0;
                    end
                    end
                    end
                                        
                   app.modimage=uint8(NImg);
                    display('done');


                case'robert_V'
    

                 app.modimage=app.orimage;
                    img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                filtter=[0 1 0 ; 0 0 0 ; 0 -1 0];
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                    display('done');
                 case'robert_H'
    

                 app.modimage=app.orimage;
                    img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                filtter=[0 0 0 ; 1 0 -1 ; 0 0 0];
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                    display('done');
                    case'robert_DL'
    

                 app.modimage=app.orimage;
                    img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                filtter=[1 0 0 ; 0 0 0 ; 0 0 -1];
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                    display('done');
                    case'robert_DR'
    

                 app.modimage=app.orimage;
                    img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                filtter=[0 0 1 ; 0 0 0 ; -1 0 0];
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                    display('done');
                    
                case'Correlation'
                 
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
             
                  app.modimage=app.orimage;
                  img=app.modimage;
                %UNTITLED3 Summary of this function goes here
                % Detailed explanation goes here
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                filtter=[1 1 0 ; 0 0 0 ; -3 -1 0];
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                [h w l] =size(img);
                [fh fw] = size(filtter);
                NImg=zeros(h,w);
                FImg=zeros(h+2,w+2,l);
                for z=1:l
                for i=1:h
                for j=1:w
                FImg(i+1,j+1,z)=img(i,j,z);
                end
                end
                end
                
                
                m = zeros(fh,fw);
                a=zeros(fh,fw);
                su=0;
                for z=1:l
                for i=1:h
                for j=1:w
                m = FImg(i:i+fh-1, j:j+fh-1,z);
                [mh,mw]=size(m);
                a=(m .* filtter);
                    for th=1:mh
                        for tw=1:mw
                            su=su+a(th,tw);
                        end
                    end
                NImg(i,j,z) = su;
                su=0;
                end
                end
                end
                 app.modimage=uint8(NImg);
                    display('done');


                case'MidPointFilter'
                    app.modimage=app.orimage;
                    img=app.modimage;
                    widthFilter=[1;-1;-2]; 
                    hightFiltter=[1 2 3];
                    [h w l] =size(img);
                    NImg=zeros(h,w);
                    FImg=zeros(h+2,w+2);
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    FImg(i+1,j+1,z)=img(i,j,z);
                    end
                    end
                    end
                    
                    for z=1:l
                    for i=1:h
                    for j=1:w
                    maxx = max(max(FImg(i:i+hightFiltter-1,j:j+widthFilter-1),z));
                    minn = min(min(FImg(i:i+hightFiltter-1,j:j+widthFilter-1),z));
                    NImg(i,j,z)= (maxx+minn)/2;
                    
                    end


                    end
                    
                    end

                   app.modimage=uint8(NImg);
                    display('done');


                otherwise 
                    display ('error');
            end    
            
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.tmpimage=(value/100*app.modimage)+((1-(value/100))*app.orimage);
            IM=imshow(app.tmpimage,'Parent',app.image,'XData',[1 app.image.Position(3)],'YData',[1 app.image.Position(4)]); 
            app.image.XLim=[0 IM.XData(2)];    
            app.image.YLim=[0 IM.YData(2)];
            
        end

        % Button pushed function: showhisgramButton
        function showhisgramButtonPushed(app, event)
                    img1=app.modimage;
                    img2=double(img1);
                    [w,h,l]=size(img2);
                    hist=zeros(w+h,1,l);
                    c=["Red","Green","Blue"];
                    
                    if l==1
                        for i=1:w
                            for j=1:h
                                x=img2(i,j);
                                hist(x+1,1)=hist(x+1,1)+1;
                            end
                        end
                           bar(hist),title('grays');
                    
                    else
                        
                        for z=1:l
                            for j=1:w
                                for i=1:h
                                h=img1(i,j,z);
                                hist(h+1,1,z)=hist(h+1,1,z)+1;
                                end
                            end
                            subplot(3,1,z),bar(hist(:,:,z)),title(c(z));
                        end
                    end
    
        end

        % Button pushed function: FouriertransformButton
        function FouriertransformButtonPushed(app, event)
           
            imdata = app.modimage;
            [w h l]=size(imdata);
             if l~=1
                imdata = rgb2gray(imdata);
             end 
            F = fft2(imdata);
            %get the centered spectrum
            Fsh = fftshift(F);
            S2 = log(1+abs(Fsh));
            figure(1);imshow(S2,[]);title('Fourier transform of an image')


        end

        % Button pushed function: inverusFourierButton
        function inverusFourierButtonPushed(app, event)
            
            imdata = app.modimage;
             [w h l]=size(imdata);
             if l~=1
                imdata = rgb2gray(imdata);
             end 
             F = fft2(imdata);
         Fsh = fftshift(F);
         S2 = log(1+abs(Fsh));
         figure(1);imshow(S2,[]);title('Fourier transform of an image')
         F = ifftshift(Fsh);
         f = ifft2(F);
         figure(2);imshow(f,[]),title('reconstructed Image')


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowsButton = uibutton(app.UIFigure, 'push');
            app.BrowsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsButtonPushed, true);
            app.BrowsButton.Text = 'Brows';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TypeAlgorthmDropDownLabel = uilabel(app.UIFigure);
            app.TypeAlgorthmDropDownLabel.Text = 'Type Algorthm';
            app.TypeAlgorthmDropDown = uidropdown(app.UIFigure);
            app.TypeAlgorthmDropDown.Items = {'NAN', 'RGB2GRAY', 'GARY2BINARY', 'Gamma Correction', 'Expontional', 'log', 'histogram equaltions', 'WeightFilter', 'MeanFilter', 'brightness +', 'brightness *', 'drakness -', 'drakness /', 'negative', 'Contrast Stretching 6.60', 'Contrast Stretching 6.1000', 'Mini Filter', 'max Filter', 'median filter', 'salt & pepper noise', 'Butterworth_highpass', 'Butterworth_lowpass', 'ideal_high', 'ideal_low', 'Gaussian_highpas', 'Gaussian_lowpass', 'Gamma_noise 1.3 5', 'Exponential_noise .05', 'rayleigh_noise 6 90', 'gaussian_noise 12 30', 'uniform_noise 50 1500', 'point_detection', 'Point Sharpening', 'line_sharpeningV', 'line_sharpeningH', 'line_sharpeningDL', 'line_sharpeningDR', 'line_DetectionV', 'line_DetectionDR', 'line_DetectionDL', 'line_DetectionH', 'robert_V', 'robert_H', 'robert_DL', 'robert_DR', 'sebel_V', 'sebel_H', 'sebel_DL', 'sebel_DR', 'Correlation', 'MidPointFilter'};
            app.TypeAlgorthmDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeAlgorthmDropDownValueChanged, true);
            app.TypeAlgorthmDropDown.Value = 'NAN';
            app.PhotoPathEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoPathEditFieldLabel.Text = 'Photo Path';
            app.PhotoPathEditField = uieditfield(app.UIFigure, 'text');
            app.PhotoPathEditField.Editable = 'off';
            app.image = uiaxes(app.UIFigure);
            app.image.XLim = [0 1];
            app.image.XTick = [];
            app.image.XTickLabel = '';
            app.image.YTick = [];
            app.image.YTickLabel = '';
            app.image.ZTick = [];
            app.image.ZTickLabel = '';
            app.showhisgramButton = uibutton(app.UIFigure, 'push');
            app.showhisgramButton.ButtonPushedFcn = createCallbackFcn(app, @showhisgramButtonPushed, true);
            app.showhisgramButton.Text = 'show hisgram';
            app.FouriertransformButton = uibutton(app.UIFigure, 'push');
            app.FouriertransformButton.ButtonPushedFcn = createCallbackFcn(app, @FouriertransformButtonPushed, true);
            app.FouriertransformButton.Text = 'Fourier transform';
            app.inverusFourierButton = uibutton(app.UIFigure, 'push');
            app.inverusFourierButton.ButtonPushedFcn = createCallbackFcn(app, @inverusFourierButtonPushed, true);
            app.inverusFourierButton.Text = 'inverus Fourier ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end